classdef sketch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        WorkflowLabel           matlab.ui.control.Label
        LoadTrainingDataButton  matlab.ui.control.Button
        TrainingButton          matlab.ui.control.Button
        LoadGalleryButton       matlab.ui.control.Button
        TestingButton           matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadTrainingDataButton
        function LoadTrainingDataButtonPushed(app, event)
            databasepath = uigetdir;
            trainingdataset = loadData(databasepath);
            trainingdataset = normalize(trainingdataset);
            
            traindatafeatures = extractAllFeatures(trainingdataset, 'display', true);
            
            save('trainingdataset.mat','trainingdataset','traindatafeatures');
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            load('trainingdataset.mat');
            bagSet = train(trainingdataset, traindatafeatures, 'display', true);
            save('trainingResult.mat',bagSet);
            
        end

        % Button pushed function: LoadGalleryButton
        function LoadGalleryButtonPushed(app, event)
           load('trainingResult.mat');
            
            gallerySet = loadData('dataset\testing\photos', false);
            gallerySet = normalize(gallerySet);
            T = extractAllFeatures(gallerySet, 'display', true);
            GPHI = prepareGalleryData(bagSet, gallery, T);
            
            save('GPhi.mat','GPHI','gallerySet');
        end

        % Button pushed function: TestingButton
        function TestingButtonPushed(app, event)
            load('trainingResult.mat');
            load('GPhi.mat');
            
            [FileName,PathName,~] = uigetfile('*.jpg');
            sketchpath = fullfile(PathName,FileName);
            
            sketchimg = imread(sketchpath);            
            imshow(sketchimg,'Parent',app.UIAxes);
            
            if size(sketchimg, 3) ~= 1
                sketchimg = rgb2gray(sketchimg);
            end
            sketchimg = normalize(sketchimg);
            result = testing(sketchimg, bagSet, GPHI);
            
            imshow(gallerySet(:,:,result(2)),'Parent',app.UIAxes_2);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WorkflowLabel = uilabel(app.UIFigure);
            app.WorkflowLabel.Text = 'Workflow';
            app.LoadTrainingDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainingDataButtonPushed, true);
            app.LoadTrainingDataButton.Text = 'Load Training Data';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.LoadGalleryButton = uibutton(app.UIFigure, 'push');
            app.LoadGalleryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGalleryButtonPushed, true);
            app.LoadGalleryButton.Text = 'Load Gallery';
            app.TestingButton = uibutton(app.UIFigure, 'push');
            app.TestingButton.ButtonPushedFcn = createCallbackFcn(app, @TestingButtonPushed, true);
            app.TestingButton.Text = 'Testing';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = sketch

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end